classdef ShowFlow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TimeControlButtonGroup        matlab.ui.container.ButtonGroup
        TimecontrolSlider             matlab.ui.control.Slider
        FrameEditFieldLabel           matlab.ui.control.Label
        FrameEditField                matlab.ui.control.NumericEditField
        TimeEditFieldLabel            matlab.ui.control.Label
        TimeEditField                 matlab.ui.control.NumericEditField
        ChannelDropDownLabel          matlab.ui.control.Label
        ChannelDropDown               matlab.ui.control.DropDown
        FlowarrowscontrolButtonGroup  matlab.ui.container.ButtonGroup
        ScalefactorSpinnerLabel       matlab.ui.control.Label
        ScalefactorSpinner            matlab.ui.control.Spinner
        XdecimationSpinnerLabel       matlab.ui.control.Label
        XdecimationSpinner            matlab.ui.control.Spinner
        YdecimationSpinnerLabel       matlab.ui.control.Label
        YdecimationSpinner            matlab.ui.control.Spinner
        BorderoffsetSpinnerLabel      matlab.ui.control.Label
        BorderoffsetSpinner           matlab.ui.control.Spinner
        UIAxes_Flow                   matlab.ui.control.UIAxes
        UIAxes_Orientation            matlab.ui.control.UIAxes
        UIAxes_Magnitude              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        DATA % Description        
        him
        hFlowArrow
        idxC
        idxF
        RV
        CV
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Initialize(app, DATA)
            app.idxC = 1;
            app.idxF = 1;
            
            s = DATA.stack_reg(:,:, app.idxF, app.idxC);
            o = DATA.Orientation(:,:, app.idxF, app.idxC);
            m =  DATA.Magnitude(:,:, app.idxF, app.idxC);
            him(1) = imshow(s,[],'Parent',app.UIAxes_Flow);
            him(2) = imshow(o,[],'Parent',app.UIAxes_Orientation);
            him(3) = imshow(m,[],'Parent',app.UIAxes_Magnitude);
            
            app.him = him;
            
            app.UIAxes_Flow.CLim = [min(s(:)) max(s(:))]; 
            app.UIAxes_Orientation.CLim = [min(o(:)) max(o(:))]; 
            app.UIAxes_Magnitude.CLim = [min(m(:)) max(m(:))];
            
            app.ScalefactorSpinner.Value = 1;
            app.ScalefactorSpinner.Step = 0.1;
            app.ScalefactorSpinner.Limits = [0.1 10];
            
            app.XdecimationSpinner.Step = 1;                      
            app.XdecimationSpinner.Value = 4;
            app.XdecimationSpinner.Limits = [1 round(DATA.DimX/10)];
            
            app.YdecimationSpinner.Step = 1;                      
            app.YdecimationSpinner.Value = 4;
            app.YdecimationSpinner.Limits = [1 round(DATA.DimY/10)];
            
            app.BorderoffsetSpinner.Step = 1;
            app.BorderoffsetSpinner.Value = 1;
            app.YdecimationSpinner.Limits = [1 20];


            hold(app.UIAxes_Flow,'on')
            %
            ScaleFactor = 1;
            XDecimationFactor = 4;
            YDecimationFactor = 4;
            borderOffset = 1;
            
            RV = borderOffset:YDecimationFactor:(DATA.DimY-borderOffset+1);
            CV = borderOffset:XDecimationFactor:(DATA.DimX-borderOffset+1);
            [X, Y] = meshgrid(CV,RV);            
            velocityX = DATA.flow{app.idxF,app.idxC}.Vx;
            velocityY = DATA.flow{app.idxF,app.idxC}.Vy;
            velocityX = velocityX(RV,CV);
            velocityY = velocityY(RV,CV);
            velocityX = velocityX.*ScaleFactor;
            velocityY = velocityY.*ScaleFactor;
            app.RV = RV;
            app.CV = CV;
            %
            a = quiver(X,Y,velocityX,velocityY,'Parent',app.UIAxes_Flow);
            a.AutoScale = 'off';
            hold(app.UIAxes_Flow,'off')
            app.hFlowArrow = a;


            app.UIAxes_Orientation.Colormap = hsv(512);
            app.UIAxes_Magnitude.Colormap = hot(512);
            hcO = colorbar(app.UIAxes_Orientation);
            hcO.Label.String = 'Direction (°)';
            
            hcM = colorbar(app.UIAxes_Magnitude);
            hcM.Label.String = 'speed (\mum\cdotmin^{-1})';
            app.TimecontrolSlider.Limits = [1 DATA.DimT];
            
            app.ChannelDropDown.Items = DATA.ChannelName;
            app.ChannelDropDown.Value = DATA.ChannelName{app.idxC};
            
            app.DATA = DATA;

            
        end

        % Value changing function: TimecontrolSlider
        function UpdateFrames(app, event)
            app.idxF = round(event.Value);             
            app.him(1).CData = app.DATA.stack_reg(:,:, app.idxF, app.idxC);
            app.him(2).CData = app.DATA.Orientation(:,:,app.idxF, app.idxC);
            app.him(3).CData = app.DATA.Magnitude(:,:,app.idxF, app.idxC);
            
            app.FrameEditField.Value = app.idxF;
            app.TimeEditField.Value  = minutes(app.DATA.t(app.idxF));
         
            
            velocityX = app.DATA.flow{app.idxF,app.idxC}.Vx;
            velocityY = app.DATA.flow{app.idxF,app.idxC}.Vy;
            velocityX = velocityX(app.RV,app.CV);
            velocityY = velocityY(app.RV,app.CV);
            velocityX = velocityX.*app.ScalefactorSpinner.Value;
            velocityY = velocityY.*app.ScalefactorSpinner.Value;
            
           app.hFlowArrow.UData = velocityX;
           app.hFlowArrow.VData = velocityY;
            
    
        end

        % Value changed function: BorderoffsetSpinner, 
        % ScalefactorSpinner, XdecimationSpinner, YdecimationSpinner
        function ArrowSampling(app, event)
            
            RV = app.BorderoffsetSpinner.Value:app.YdecimationSpinner.Value:(app.DATA.DimY-app.BorderoffsetSpinner.Value+1);
            CV = app.BorderoffsetSpinner.Value:app.XdecimationSpinner.Value:(app.DATA.DimX-app.BorderoffsetSpinner.Value+1);                        
            [X, Y] = meshgrid(CV,RV);            
            velocityX = app.DATA.flow{app.idxF,app.idxC}.Vx;
            velocityY = app.DATA.flow{app.idxF,app.idxC}.Vy;
            velocityX = velocityX(RV,CV);
            velocityY = velocityY(RV,CV);
            velocityX = velocityX.*app.ScalefactorSpinner.Value;
            velocityY = velocityY.*app.ScalefactorSpinner.Value;
            app.RV = RV;
            app.CV = CV;
            app.hFlowArrow.XData = X;
            app.hFlowArrow.YData = Y;
            app.hFlowArrow.UData = velocityX;
            app.hFlowArrow.VData = velocityY;
            %


        end

        % Value changed function: ChannelDropDown
        function ChangeChannel(app, event)
             
            [~,app.idxC] = ismember(app.ChannelDropDown.Value,app.ChannelDropDown.Items);
            
            app.him(1).CData = app.DATA.stack_reg(:,:, app.idxF, app.idxC);
            app.him(2).CData = app.DATA.Orientation(:,:,app.idxF, app.idxC);
            app.him(3).CData = app.DATA.Magnitude(:,:,app.idxF, app.idxC);
            RV = app.BorderoffsetSpinner.Value:app.YdecimationSpinner.Value:(app.DATA.DimY-app.BorderoffsetSpinner.Value+1);
            CV = app.BorderoffsetSpinner.Value:app.XdecimationSpinner.Value:(app.DATA.DimX-app.BorderoffsetSpinner.Value+1);                        
            [X, Y] = meshgrid(CV,RV);            
            velocityX = app.DATA.flow{app.idxF,app.idxC}.Vx;
            velocityY = app.DATA.flow{app.idxF,app.idxC}.Vy;
            velocityX = velocityX(RV,CV);
            velocityY = velocityY(RV,CV);
            velocityX = velocityX.*app.ScalefactorSpinner.Value;
            velocityY = velocityY.*app.ScalefactorSpinner.Value;
            app.RV = RV;
            app.CV = CV;
            app.hFlowArrow.XData = X;
            app.hFlowArrow.YData = Y;
            app.hFlowArrow.UData = velocityX;
            app.hFlowArrow.VData = velocityY;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TimeControlButtonGroup = uibuttongroup(app.UIFigure);
            app.TimeControlButtonGroup.Title = 'Time Control';
            app.TimecontrolSlider = uislider(app.TimeControlButtonGroup);
            app.TimecontrolSlider.MajorTicks = [];
            app.TimecontrolSlider.MajorTickLabels = {};
            app.TimecontrolSlider.ValueChangingFcn = createCallbackFcn(app, @UpdateFrames, true);
            app.TimecontrolSlider.MinorTicks = [];
            app.FrameEditFieldLabel = uilabel(app.TimeControlButtonGroup);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.FrameEditField = uieditfield(app.TimeControlButtonGroup, 'numeric');
            app.FrameEditField.ValueDisplayFormat = '%.0f';
            app.FrameEditField.Editable = 'off';
            app.TimeEditFieldLabel = uilabel(app.TimeControlButtonGroup);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.TimeControlButtonGroup, 'numeric');
            app.TimeEditField.ValueDisplayFormat = '%11.4g min';
            app.TimeEditField.Editable = 'off';
            app.ChannelDropDownLabel = uilabel(app.UIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.UIFigure);
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChangeChannel, true);
            app.FlowarrowscontrolButtonGroup = uibuttongroup(app.UIFigure);
            app.FlowarrowscontrolButtonGroup.Title = 'Flow arrows control';
            app.ScalefactorSpinnerLabel = uilabel(app.FlowarrowscontrolButtonGroup);
            app.ScalefactorSpinnerLabel.Text = 'Scale factor';
            app.ScalefactorSpinner = uispinner(app.FlowarrowscontrolButtonGroup);
            app.ScalefactorSpinner.ValueChangedFcn = createCallbackFcn(app, @ArrowSampling, true);
            app.XdecimationSpinnerLabel = uilabel(app.FlowarrowscontrolButtonGroup);
            app.XdecimationSpinnerLabel.Text = 'X decimation';
            app.XdecimationSpinner = uispinner(app.FlowarrowscontrolButtonGroup);
            app.XdecimationSpinner.ValueDisplayFormat = '%.0f';
            app.XdecimationSpinner.ValueChangedFcn = createCallbackFcn(app, @ArrowSampling, true);
            app.YdecimationSpinnerLabel = uilabel(app.FlowarrowscontrolButtonGroup);
            app.YdecimationSpinnerLabel.Text = 'Y decimation';
            app.YdecimationSpinner = uispinner(app.FlowarrowscontrolButtonGroup);
            app.YdecimationSpinner.ValueDisplayFormat = '%.0f';
            app.YdecimationSpinner.ValueChangedFcn = createCallbackFcn(app, @ArrowSampling, true);
            app.BorderoffsetSpinnerLabel = uilabel(app.FlowarrowscontrolButtonGroup);
            app.BorderoffsetSpinnerLabel.Text = 'Border offset';
            app.BorderoffsetSpinner = uispinner(app.FlowarrowscontrolButtonGroup);
            app.BorderoffsetSpinner.ValueDisplayFormat = '%.0f';
            app.BorderoffsetSpinner.ValueChangedFcn = createCallbackFcn(app, @ArrowSampling, true);
            app.UIAxes_Flow = uiaxes(app.UIFigure);
            title(app.UIAxes_Flow, 'Optical Flow')
            app.UIAxes_Orientation = uiaxes(app.UIFigure);
            title(app.UIAxes_Orientation, 'Orientation')
            app.UIAxes_Magnitude = uiaxes(app.UIFigure);
            title(app.UIAxes_Magnitude, 'Magnitude')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ShowFlow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)Initialize(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end